classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID5CUIFigure           matlab.ui.Figure
        ParametersInitialConiditionsPanel  matlab.ui.container.Panel
        TabGroup                  matlab.ui.container.TabGroup
        StudentsTab               matlab.ui.container.Tab
        icSExpC_Field             matlab.ui.control.NumericEditField
        icSRecC_Field             matlab.ui.control.NumericEditField
        icSExpNC_Field            matlab.ui.control.NumericEditField
        icSRecNC_Field            matlab.ui.control.NumericEditField
        icSExpCLabel              matlab.ui.control.Label
        icSExpC_Slider            matlab.ui.control.Slider
        icSRecCLabel              matlab.ui.control.Label
        icSRecC_Slider            matlab.ui.control.Slider
        icSExpNCLabel             matlab.ui.control.Label
        icSExpNC_Slider           matlab.ui.control.Slider
        icSRecNCLabel             matlab.ui.control.Label
        icSRecNC_Slider           matlab.ui.control.Slider
        soSC_Field                matlab.ui.control.NumericEditField
        soSCLabel                 matlab.ui.control.Label
        soSC_Slider               matlab.ui.control.Slider
        soSNC_Field               matlab.ui.control.NumericEditField
        soSNCLabel                matlab.ui.control.Label
        soSNC_Slider              matlab.ui.control.Slider
        AdminTeachersTab          matlab.ui.container.Tab
        icATExpHRLabel            matlab.ui.control.Label
        icATExpHR_Slider          matlab.ui.control.Slider
        icATExpHR_Field           matlab.ui.control.NumericEditField
        icATRecHR_Field           matlab.ui.control.NumericEditField
        icATRecHRLabel            matlab.ui.control.Label
        icATRecHR_Slider          matlab.ui.control.Slider
        icATExpLR_Field           matlab.ui.control.NumericEditField
        icATExpLRLabel            matlab.ui.control.Label
        icATExpLR_Slider          matlab.ui.control.Slider
        icATRecLR_Field           matlab.ui.control.NumericEditField
        icATRecLRLabel            matlab.ui.control.Label
        icATRecLR_Slider          matlab.ui.control.Slider
        soATHR_Field              matlab.ui.control.NumericEditField
        soATLR_Field              matlab.ui.control.NumericEditField
        soATHRLabel               matlab.ui.control.Label
        soATHR_Slider             matlab.ui.control.Slider
        soATLRLabel               matlab.ui.control.Label
        soATLR_Slider             matlab.ui.control.Slider
        StaffTab                  matlab.ui.container.Tab
        icStaffExpHR_Field        matlab.ui.control.NumericEditField
        icStaffRecHR_Field        matlab.ui.control.NumericEditField
        icStaffExpLR_Field        matlab.ui.control.NumericEditField
        icStaffRecLR_Field        matlab.ui.control.NumericEditField
        icStaffExpHRLabel         matlab.ui.control.Label
        icStaffExpHR_Slider       matlab.ui.control.Slider
        icStaffRecHRLabel         matlab.ui.control.Label
        icStaffRecHR_Slider       matlab.ui.control.Slider
        icStaffExpLRLabel         matlab.ui.control.Label
        icStaffExpLR_Slider       matlab.ui.control.Slider
        icStaffRecLRLabel         matlab.ui.control.Label
        icStaffRecLR_Slider       matlab.ui.control.Slider
        soStaffHR_Field           matlab.ui.control.NumericEditField
        soStaffLR_Field           matlab.ui.control.NumericEditField
        soStaffHRLabel            matlab.ui.control.Label
        soStaffHR_Slider          matlab.ui.control.Slider
        soStaffLRLabel            matlab.ui.control.Label
        soStaffLR_Slider          matlab.ui.control.Slider
        scaleTrackingSliderLabel  matlab.ui.control.Label
        scaleTracking_Slider      matlab.ui.control.Slider
        scaleTracking_Field       matlab.ui.control.NumericEditField
        incAsymp_Field            matlab.ui.control.NumericEditField
        incAsympSliderLabel       matlab.ui.control.Label
        incAsymp_Slider           matlab.ui.control.Slider
        propNC_Field              matlab.ui.control.NumericEditField
        propNCSliderLabel         matlab.ui.control.Label
        propNC_Slider             matlab.ui.control.Slider
        InputPreset1_Button       matlab.ui.control.Button
        InputPreset2_Button       matlab.ui.control.Button
        InputPreset3_Button       matlab.ui.control.Button
        InputPreset4_Button       matlab.ui.control.Button
        PlotsPanel                matlab.ui.container.Panel
        UIAxes1                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        OutputPreset1_Button      matlab.ui.control.Button
        OutputPreset2_Button      matlab.ui.control.Button
        OutputPreset3_Button      matlab.ui.control.Button
        AT_HRCheckBox             matlab.ui.control.CheckBox
        AT_LRCheckBox             matlab.ui.control.CheckBox
        Staff_HRCheckBox          matlab.ui.control.CheckBox
        Staff_LRCheckBox          matlab.ui.control.CheckBox
        Students_CCheckBox        matlab.ui.control.CheckBox
        Students_NCCheckBox       matlab.ui.control.CheckBox
        DEATHSGaugeLabel          matlab.ui.control.Label
        Deaths_Gauge              matlab.ui.control.Gauge
    end


    properties (Access = public)
        inputs = AppInputs()
        results %= ModelResults()
        selection = [0, 0, 0, 0, 0, 0]
    end

    methods (Access = private)
        function redraw(app)
           % plotModel(app.UIAxes1, app.selectedField, app.results)
           app.plotPop(1, "totalInfPop");
           app.plotPop(2, "totalDeadPop");
        end
    
        function run(app) 
            % FIXME: runModel needs to parse AppInputs correctly
            app.results = runModel(app.inputs);
        end
    
        function runAndRedraw(app)
            app.run();
            app.redraw();
        end
    
        function plotPop(app, axIdx, label)
            x = app.results.t;
            y = app.pops.extract(label);
            if axIdx == 1 
                ax = app.UIAxes1;
            else
                ax = app.UIAxes2;
            end
            cla(ax);
            xtitle(label)
            plot(ax, x, y);
        end
    
        function loadInputs(inputs)
            % TODO
        end
    
    end


    methods (Access = private)

        % Value changed function: icSExpC_Field
        function icSExpC_FieldValueChanged(app, event)
            value = app.icSExpC_Field.Value;
            app.icSExpC_Slider.Value = value;
            app.inputs.icSExpC = value;
            app.runAndRedraw();
        end

        % Value changed function: icSExpNC_Field
        function icSExpNC_FieldValueChanged(app, event)
            value = app.icSExpNC_Field.Value;
            app.icSExpNC_Slider.Value = value;
            app.inputs.icSExpNC = value;
            app.runAndRedraw();
        end

        % Value changed function: icSExpC_Slider
        function icSExpC_SliderValueChanged(app, event)
            value = app.icSExpC_Slider.Value;
            app.icSExpC_Field.Value = value;
            app.inputs.icSExpNC = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icSRecC_Slider
        function icSRecC_SliderValueChanged(app, event)
            value = app.icSRecC_Slider.Value;
            app.icSRecC_Field.Value = value;
            app.inputs.icSRecC = value;
            app.runAndRedraw(); 
        end

        % Value changed function: icSExpNC_Slider
        function icSExpNC_SliderValueChanged(app, event)
            value = app.icSExpNC_Slider.Value;
            app.icSExpNC_Field.Value = value;
            app.inputs.icSExpNC = value;
            app.runAndRedraw(); 
            
        end

        % Value changed function: icSRecNC_Slider
        function icSRecNC_SliderValueChanged(app, event)
            value = app.icSRecNC_Slider.Value;
            app.icSRecNC_Field.Value = value;
            app.inputs.icSRecNC = value;
            app.runAndRedraw(); 
        end

        % Value changed function: soSC_Field
        function soSC_FieldValueChanged(app, event)
            value = app.soSC_Field.Value;
            app.soSC_Slider.Value = value;
            app.inputs.soSC = value;
            app.runAndRedraw(); 
        end

        % Value changed function: soSNC_Field
        function soSNC_FieldValueChanged(app, event)
            value = app.soSNC_Field.Value;
            app.soSNC_Slider.Value = value;
            app.inputs.soSNC = value;
            app.runAndRedraw();
        end

        % Value changed function: soSC_Slider
        function soSC_SliderValueChanged(app, event)
            value = app.soSC_Slider.Value;
            app.soSC_Field.Value = value;
            app.inputs.soSC = value;
            app.runAndRedraw();
        end

        % Value changed function: soSNC_Slider
        function soSNC_SliderValueChanged(app, event)
            value = app.soSNC_Slider.Value;
            app.soSNC_Field.Value = value;
            app.inputs.soSNC = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icATExpHR_Slider
        function icATExpHR_SliderValueChanged(app, event)
            value = app.icATExpHR_Slider.Value;
            app.icATExpHR_Field.Value = value;
            app.inputs.icATExpHR= value;
            app.runAndRedraw();
        end

        % Value changed function: icATExpHR_Field
        function icATExpHR_FieldValueChanged(app, event)
            value = app.icATExpHR_Field.Value;
            app.icATExpHR_Slider.Value = value;
            app.inputs.icATExpHR= value;
            app.runAndRedraw();
            
        end

        % Value changed function: icATRecHR_Slider
        function icATRecHR_SliderValueChanged(app, event)
            value = app.icATRecHR_Slider.Value;
            app.icATRecHR_Field.Value = value;
            app.inputs.icATRecHR= value;
            app.runAndRedraw();
            
        end

        % Value changed function: icATRecHR_Field
        function icATRecHR_FieldValueChanged(app, event)
            value = app.icATRecHR_Field.Value;
            app.icATRecHR_Slider.Value = value;
            app.inputs.icATRecHR= value;
            app.runAndRedraw();
            
        end

        % Value changed function: icATExpLR_Slider
        function icATExpLR_SliderValueChanged(app, event)
            value = app.icATExpLR_Slider.Value;
            app.icATExpLR_Field.Value = value;
            app.inputs.icATExpLR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icATExpLR_Field
        function icATExpLR_FieldValueChanged(app, event)
            value = app.icATExpLR_Field.Value;
            app.icATExpLR_Slider.Value = value;
            app.inputs.icATExpLR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icATRecLR_Slider
        function icATRecLR_SliderValueChanged(app, event)
            value = app.icATRecLR_Slider.Value;
            app.icATRecLR_Field.Value = value;
            app.inputs.icATRecLR = value;
            app.runAndRedraw();
        end

        % Value changed function: icATRecLR_Field
        function icATRecLR_FieldValueChanged(app, event)
            value = app.icATRecLR_Field.Value;
            app.icATRecLR_Slider.Value = value;
            app.inputs.icATRecLR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: soATHR_Slider
        function soATHR_SliderValueChanged(app, event)
            value = app.soATHR_Slider.Value;
            app.soATHR_Field.Value = value;
            app.inputs.soATHR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: soATHR_Field
        function soATHR_FieldValueChanged(app, event)
            value = app.soATHR_Field.Value;
            app.soATHR_Slider.Value = value;
            app.inputs.soATHR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: soATLR_Slider
        function soATLR_SliderValueChanged(app, event)
            value = app.soATLR_Slider.Value;
            app.soATLR_Field.Value = value;
            app.inputs.soATLR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: soATLR_Field
        function soATLR_FieldValueChanged(app, event)
            value = app.soATLR_Field.Value;
            app.soATLR_Slider.Value = value;
            app.inputs.soATLR = value;
            app.runAndRedraw();
        end

        % Value changed function: icStaffExpHR_Slider
        function icStaffExpHR_SliderValueChanged(app, event)
            value = app.icStaffExpHR_Slider.Value;
            app.icStaffExpHR_Field.Value = value;
            app.inputs.icStaffExpHR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icStaffExpHR_Field
        function icStaffExpHR_FieldValueChanged(app, event)
            value = app.icStaffExpHR_Field.Value;
            app.icStaffExpHR_Slider.Value = value;
            app.inputs.icStaffExpHR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icStaffRecHR_Slider
        function icStaffRecHR_SliderValueChanged(app, event)
            value = app.icStaffRecHR_Slider.Value;
            app.icStaffRecHR_Field.Value = value;
            app.inputs.icStaffRecHR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icStaffRecHR_Field
        function icStaffRecHR_FieldValueChanged(app, event)
            value = app.icStaffRecHR_Field.Value;
            app.icStaffRecHR_Slider.Value = value;
            app.inputs.icStaffRecHR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icStaffExpLR_Slider
        function icStaffExpLR_SliderValueChanged(app, event)
            value = app.icStaffExpLR_Slider.Value;
            app.icStaffExpLR_Field.Value = value;
            app.inputs.icStaffExpLR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icStaffExpLR_Field
        function icStaffExpLR_FieldValueChanged(app, event)
            value = app.icStaffExpLR_Field.Value;
            app.icStaffExpLR_Slider.Value = value;
            app.inputs.icStaffExpLR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icStaffRecLR_Slider
        function icStaffRecLR_SliderValueChanged(app, event)
            value = app.icStaffRecLR_Slider.Value;
            app.icStaffRecLR_Field.Value = value;
            app.inputs.icStaffRecLR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: icStaffRecLR_Field
        function icStaffRecLR_FieldValueChanged(app, event)
            value = app.icStaffRecLR_Field.Value;
            app.icStaffRecLR_Slider.Value = value;
            app.inputs.icStaffRecLR = value;
            app.runAndRedraw();
            
        end

        % Value changed function: soStaffHR_Slider
        function soStaffHR_SliderValueChanged(app, event)
            value = app.soStaffHR_Slider.Value;
            app.soStaffHR_Field.Value = value;
            app.inputs.soStaffHR= value;
            app.runAndRedraw();
        end

        % Value changed function: soStaffHR_Field
        function soStaffHR_FieldValueChanged(app, event)
            value = app.soStaffHR_Field.Value;
            app.soStaffHR_Slider.Value = value;
            app.inputs.soStaffHR= value;
            app.runAndRedraw();
            
        end

        % Value changed function: soStaffLR_Slider
        function soStaffLR_SliderValueChanged(app, event)
            value = app.soStaffLR_Slider.Value;
            app.soStaffLR_Field.Value = value;
            app.inputs.soStaffLR= value;
            app.runAndRedraw();
            
        end

        % Value changed function: soStaffLR_Field
        function soStaffLR_FieldValueChanged(app, event)
            value = app.soStaffLR_Field.Value;
            app.soStaffLR_Slider.Value = value;
            app.inputs.soStaffLR= value;
            app.runAndRedraw();
            
        end

        % Value changed function: scaleTracking_Slider
        function scaleTracking_SliderValueChanged(app, event)
            value = app.scaleTracking_Slider.Value;
            app.scaleTracking_Field.Value = value;
            app.inputs.scaleTracking= value;
            app.runAndRedraw();
            
        end

        % Value changed function: scaleTracking_Field
        function scaleTracking_FieldValueChanged(app, event)
            value = app.scaleTracking_Field.Value;
            app.scaleTracking_Slider.Value = value;
            app.inputs.scaleTracking= value;
            app.runAndRedraw();
        end

        % Value changed function: propNC_Slider
        function propNC_SliderValueChanged(app, event)
            value = app.propNC_Slider.Value;
            app.propNC_Field.Value = value;
            app.inputs.propNC= value;
            app.runAndRedraw();
            
        end

        % Value changed function: propNC_Field
        function propNC_FieldValueChanged(app, event)
            value = app.propNC_Field.Value;
            app.propNC_Slider.Value = value;
            app.inputs.propNC= value;
            app.runAndRedraw();
        end

        % Value changed function: incAsymp_Slider
        function incAsymp_SliderValueChanged(app, event)
            value = app.incAsymp_Slider.Value;
            app.incAsymp_Field.Value = value;
            app.inputs.incAsymp= value;
            app.runAndRedraw();
            
        end

        % Value changed function: incAsymp_Field
        function incAsymp_FieldValueChanged(app, event)
            value = app.incAsymp_Field.Value;
            app.incAsymp_Slider.Value = value;
            app.inputs.incAsymp= value;
            app.runAndRedraw();
            
        end

        % Button pushed function: InputPreset1_Button
        function InputPreset1_ButtonPushed(app, event)
            
        end

        % Button pushed function: InputPreset2_Button
        function InputPreset2_ButtonPushed(app, event)
            
        end

        % Button pushed function: InputPreset3_Button
        function InputPreset3_ButtonPushed(app, event)
            
        end

        % Button pushed function: InputPreset4_Button
        function InputPreset4_ButtonPushed(app, event)
            
        end

        % Button pushed function: OutputPreset1_Button
        function OutputPreset1_ButtonPushed(app, event)
            
        end

        % Value changed function: icSRecC_Field
        function icSRecC_FieldValueChanged(app, event)
            value = app.icSRecC_Field.Value;
            app.icSRecC_Slider.Value = value;
            app.inputs.icSRecC = value;
            app.runAndRedraw();
        end

        % Value changed function: AT_HRCheckBox
        function AT_HRCheckBoxValueChanged(app, event)
            value = app.AT_HRCheckBox.Value;
            
        end

        % Value changed function: AT_LRCheckBox
        function AT_LRCheckBoxValueChanged(app, event)
            value = app.AT_LRCheckBox.Value;
            
        end

        % Value changed function: Staff_HRCheckBox
        function Staff_HRCheckBoxValueChanged(app, event)
            value = app.Staff_HRCheckBox.Value;
            
        end

        % Value changed function: Staff_LRCheckBox
        function Staff_LRCheckBoxValueChanged(app, event)
            value = app.Staff_LRCheckBox.Value;
            
        end

        % Value changed function: Students_CCheckBox
        function Students_CCheckBoxValueChanged(app, event)
            value = app.Students_CCheckBox.Value;
            
        end

        % Value changed function: Students_NCCheckBox
        function Students_NCCheckBoxValueChanged(app, event)
            value = app.Students_NCCheckBox.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID5CUIFigure = uifigure;
            app.COVID5CUIFigure.Name = 'COVID5C';
            app.ParametersInitialConiditionsPanel = uipanel(app.COVID5CUIFigure);
            app.ParametersInitialConiditionsPanel.Title = 'Parameters / Initial Coniditions';
            app.TabGroup = uitabgroup(app.ParametersInitialConiditionsPanel);
            app.StudentsTab = uitab(app.TabGroup);
            app.StudentsTab.Title = 'Students';
            app.icSExpC_Field = uieditfield(app.StudentsTab, 'numeric');
            app.icSExpC_Field.ValueChangedFcn = createCallbackFcn(app, @icSExpC_FieldValueChanged, true);
            app.icSRecC_Field = uieditfield(app.StudentsTab, 'numeric');
            app.icSRecC_Field.ValueChangedFcn = createCallbackFcn(app, @icSRecC_FieldValueChanged, true);
            app.icSExpNC_Field = uieditfield(app.StudentsTab, 'numeric');
            app.icSExpNC_Field.ValueChangedFcn = createCallbackFcn(app, @icSExpNC_FieldValueChanged, true);
            app.icSRecNC_Field = uieditfield(app.StudentsTab, 'numeric');
            app.icSExpCLabel = uilabel(app.StudentsTab);
            app.icSExpCLabel.Text = 'icSExpC';
            app.icSExpC_Slider = uislider(app.StudentsTab);
            app.icSExpC_Slider.Limits = [0 25];
            app.icSExpC_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icSExpC_Slider.ValueChangedFcn = createCallbackFcn(app, @icSExpC_SliderValueChanged, true);
            app.icSRecCLabel = uilabel(app.StudentsTab);
            app.icSRecCLabel.Text = 'icSRecC';
            app.icSRecC_Slider = uislider(app.StudentsTab);
            app.icSRecC_Slider.Limits = [0 25];
            app.icSRecC_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icSRecC_Slider.ValueChangedFcn = createCallbackFcn(app, @icSRecC_SliderValueChanged, true);
            app.icSExpNCLabel = uilabel(app.StudentsTab);
            app.icSExpNCLabel.Text = 'icSExpNC';
            app.icSExpNC_Slider = uislider(app.StudentsTab);
            app.icSExpNC_Slider.Limits = [0 25];
            app.icSExpNC_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icSExpNC_Slider.ValueChangedFcn = createCallbackFcn(app, @icSExpNC_SliderValueChanged, true);
            app.icSRecNCLabel = uilabel(app.StudentsTab);
            app.icSRecNCLabel.Text = 'icSRecNC';
            app.icSRecNC_Slider = uislider(app.StudentsTab);
            app.icSRecNC_Slider.Limits = [0 25];
            app.icSRecNC_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icSRecNC_Slider.ValueChangedFcn = createCallbackFcn(app, @icSRecNC_SliderValueChanged, true);
            app.soSC_Field = uieditfield(app.StudentsTab, 'numeric');
            app.soSC_Field.ValueChangedFcn = createCallbackFcn(app, @soSC_FieldValueChanged, true);
            app.soSCLabel = uilabel(app.StudentsTab);
            app.soSCLabel.Text = 'soSC';
            app.soSC_Slider = uislider(app.StudentsTab);
            app.soSC_Slider.Limits = [0 25];
            app.soSC_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.soSC_Slider.ValueChangedFcn = createCallbackFcn(app, @soSC_SliderValueChanged, true);
            app.soSNC_Field = uieditfield(app.StudentsTab, 'numeric');
            app.soSNC_Field.ValueChangedFcn = createCallbackFcn(app, @soSNC_FieldValueChanged, true);
            app.soSNCLabel = uilabel(app.StudentsTab);
            app.soSNCLabel.Text = 'soSNC';
            app.soSNC_Slider = uislider(app.StudentsTab);
            app.soSNC_Slider.Limits = [0 25];
            app.soSNC_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.soSNC_Slider.ValueChangedFcn = createCallbackFcn(app, @soSNC_SliderValueChanged, true);
            app.AdminTeachersTab = uitab(app.TabGroup);
            app.AdminTeachersTab.Title = 'Admin/Teachers';
            app.icATExpHRLabel = uilabel(app.AdminTeachersTab);
            app.icATExpHRLabel.Text = 'icATExpHR';
            app.icATExpHR_Slider = uislider(app.AdminTeachersTab);
            app.icATExpHR_Slider.Limits = [0 25];
            app.icATExpHR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icATExpHR_Slider.ValueChangedFcn = createCallbackFcn(app, @icATExpHR_SliderValueChanged, true);
            app.icATExpHR_Field = uieditfield(app.AdminTeachersTab, 'numeric');
            app.icATExpHR_Field.ValueChangedFcn = createCallbackFcn(app, @icATExpHR_FieldValueChanged, true);
            app.icATRecHR_Field = uieditfield(app.AdminTeachersTab, 'numeric');
            app.icATRecHR_Field.ValueChangedFcn = createCallbackFcn(app, @icATRecHR_FieldValueChanged, true);
            app.icATRecHRLabel = uilabel(app.AdminTeachersTab);
            app.icATRecHRLabel.Text = 'icATRecHR';
            app.icATRecHR_Slider = uislider(app.AdminTeachersTab);
            app.icATRecHR_Slider.Limits = [0 25];
            app.icATRecHR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icATRecHR_Slider.ValueChangedFcn = createCallbackFcn(app, @icATRecHR_SliderValueChanged, true);
            app.icATExpLR_Field = uieditfield(app.AdminTeachersTab, 'numeric');
            app.icATExpLR_Field.ValueChangedFcn = createCallbackFcn(app, @icATExpLR_FieldValueChanged, true);
            app.icATExpLRLabel = uilabel(app.AdminTeachersTab);
            app.icATExpLRLabel.Text = 'icATExpLR';
            app.icATExpLR_Slider = uislider(app.AdminTeachersTab);
            app.icATExpLR_Slider.Limits = [0 25];
            app.icATExpLR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icATExpLR_Slider.ValueChangedFcn = createCallbackFcn(app, @icATExpLR_SliderValueChanged, true);
            app.icATRecLR_Field = uieditfield(app.AdminTeachersTab, 'numeric');
            app.icATRecLR_Field.ValueChangedFcn = createCallbackFcn(app, @icATRecLR_FieldValueChanged, true);
            app.icATRecLRLabel = uilabel(app.AdminTeachersTab);
            app.icATRecLRLabel.Text = 'icATRecLR';
            app.icATRecLR_Slider = uislider(app.AdminTeachersTab);
            app.icATRecLR_Slider.Limits = [0 25];
            app.icATRecLR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icATRecLR_Slider.ValueChangedFcn = createCallbackFcn(app, @icATRecLR_SliderValueChanged, true);
            app.soATHR_Field = uieditfield(app.AdminTeachersTab, 'numeric');
            app.soATHR_Field.ValueChangedFcn = createCallbackFcn(app, @soATHR_FieldValueChanged, true);
            app.soATLR_Field = uieditfield(app.AdminTeachersTab, 'numeric');
            app.soATLR_Field.ValueChangedFcn = createCallbackFcn(app, @soATLR_FieldValueChanged, true);
            app.soATHRLabel = uilabel(app.AdminTeachersTab);
            app.soATHRLabel.Text = 'soATHR';
            app.soATHR_Slider = uislider(app.AdminTeachersTab);
            app.soATHR_Slider.Limits = [0 25];
            app.soATHR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.soATHR_Slider.ValueChangedFcn = createCallbackFcn(app, @soATHR_SliderValueChanged, true);
            app.soATLRLabel = uilabel(app.AdminTeachersTab);
            app.soATLRLabel.Text = 'soATLR';
            app.soATLR_Slider = uislider(app.AdminTeachersTab);
            app.soATLR_Slider.Limits = [0 25];
            app.soATLR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.soATLR_Slider.ValueChangedFcn = createCallbackFcn(app, @soATLR_SliderValueChanged, true);
            app.StaffTab = uitab(app.TabGroup);
            app.StaffTab.Title = 'Staff';
            app.icStaffExpHR_Field = uieditfield(app.StaffTab, 'numeric');
            app.icStaffExpHR_Field.ValueChangedFcn = createCallbackFcn(app, @icStaffExpHR_FieldValueChanged, true);
            app.icStaffRecHR_Field = uieditfield(app.StaffTab, 'numeric');
            app.icStaffRecHR_Field.ValueChangedFcn = createCallbackFcn(app, @icStaffRecHR_FieldValueChanged, true);
            app.icStaffExpLR_Field = uieditfield(app.StaffTab, 'numeric');
            app.icStaffExpLR_Field.ValueChangedFcn = createCallbackFcn(app, @icStaffExpLR_FieldValueChanged, true);
            app.icStaffRecLR_Field = uieditfield(app.StaffTab, 'numeric');
            app.icStaffRecLR_Field.ValueChangedFcn = createCallbackFcn(app, @icStaffRecLR_FieldValueChanged, true);
            app.icStaffExpHRLabel = uilabel(app.StaffTab);
            app.icStaffExpHRLabel.Text = 'icStaffExpHR';
            app.icStaffExpHR_Slider = uislider(app.StaffTab);
            app.icStaffExpHR_Slider.Limits = [0 25];
            app.icStaffExpHR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icStaffExpHR_Slider.ValueChangedFcn = createCallbackFcn(app, @icStaffExpHR_SliderValueChanged, true);
            app.icStaffRecHRLabel = uilabel(app.StaffTab);
            app.icStaffRecHRLabel.Text = 'icStaffRecHR';
            app.icStaffRecHR_Slider = uislider(app.StaffTab);
            app.icStaffRecHR_Slider.Limits = [0 25];
            app.icStaffRecHR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icStaffRecHR_Slider.ValueChangedFcn = createCallbackFcn(app, @icStaffRecHR_SliderValueChanged, true);
            app.icStaffExpLRLabel = uilabel(app.StaffTab);
            app.icStaffExpLRLabel.Text = 'icStaffExpLR';
            app.icStaffExpLR_Slider = uislider(app.StaffTab);
            app.icStaffExpLR_Slider.Limits = [0 25];
            app.icStaffExpLR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icStaffExpLR_Slider.ValueChangedFcn = createCallbackFcn(app, @icStaffExpLR_SliderValueChanged, true);
            app.icStaffRecLRLabel = uilabel(app.StaffTab);
            app.icStaffRecLRLabel.Text = 'icStaffRecLR';
            app.icStaffRecLR_Slider = uislider(app.StaffTab);
            app.icStaffRecLR_Slider.Limits = [0 25];
            app.icStaffRecLR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.icStaffRecLR_Slider.ValueChangedFcn = createCallbackFcn(app, @icStaffRecLR_SliderValueChanged, true);
            app.soStaffHR_Field = uieditfield(app.StaffTab, 'numeric');
            app.soStaffHR_Field.ValueChangedFcn = createCallbackFcn(app, @soStaffHR_FieldValueChanged, true);
            app.soStaffLR_Field = uieditfield(app.StaffTab, 'numeric');
            app.soStaffLR_Field.ValueChangedFcn = createCallbackFcn(app, @soStaffLR_FieldValueChanged, true);
            app.soStaffHRLabel = uilabel(app.StaffTab);
            app.soStaffHRLabel.Text = 'soStaffHR';
            app.soStaffHR_Slider = uislider(app.StaffTab);
            app.soStaffHR_Slider.Limits = [0 25];
            app.soStaffHR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.soStaffHR_Slider.ValueChangedFcn = createCallbackFcn(app, @soStaffHR_SliderValueChanged, true);
            app.soStaffLRLabel = uilabel(app.StaffTab);
            app.soStaffLRLabel.Text = 'soStaffLR';
            app.soStaffLR_Slider = uislider(app.StaffTab);
            app.soStaffLR_Slider.Limits = [0 25];
            app.soStaffLR_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.soStaffLR_Slider.ValueChangedFcn = createCallbackFcn(app, @soStaffLR_SliderValueChanged, true);
            app.scaleTrackingSliderLabel = uilabel(app.ParametersInitialConiditionsPanel);
            app.scaleTrackingSliderLabel.Text = 'scaleTracking';
            app.scaleTracking_Slider = uislider(app.ParametersInitialConiditionsPanel);
            app.scaleTracking_Slider.Limits = [0 1];
            app.scaleTracking_Slider.MajorTicks = [0 0.125 0.25 0.375 0.5 0.625 0.75 0.875 1];
            app.scaleTracking_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.scaleTracking_Slider.ValueChangedFcn = createCallbackFcn(app, @scaleTracking_SliderValueChanged, true);
            app.scaleTracking_Field = uieditfield(app.ParametersInitialConiditionsPanel, 'numeric');
            app.scaleTracking_Field.ValueChangedFcn = createCallbackFcn(app, @scaleTracking_FieldValueChanged, true);
            app.incAsymp_Field = uieditfield(app.ParametersInitialConiditionsPanel, 'numeric');
            app.incAsymp_Field.ValueChangedFcn = createCallbackFcn(app, @incAsymp_FieldValueChanged, true);
            app.incAsympSliderLabel = uilabel(app.ParametersInitialConiditionsPanel);
            app.incAsympSliderLabel.Text = 'incAsymp';
            app.incAsymp_Slider = uislider(app.ParametersInitialConiditionsPanel);
            app.incAsymp_Slider.Limits = [0 1];
            app.incAsymp_Slider.MajorTicks = [0 0.125 0.25 0.375 0.5 0.625 0.75 0.875 1];
            app.incAsymp_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.incAsymp_Slider.ValueChangedFcn = createCallbackFcn(app, @incAsymp_SliderValueChanged, true);
            app.propNC_Field = uieditfield(app.ParametersInitialConiditionsPanel, 'numeric');
            app.propNC_Field.ValueChangedFcn = createCallbackFcn(app, @propNC_FieldValueChanged, true);
            app.propNCSliderLabel = uilabel(app.ParametersInitialConiditionsPanel);
            app.propNCSliderLabel.Text = 'propNC';
            app.propNC_Slider = uislider(app.ParametersInitialConiditionsPanel);
            app.propNC_Slider.Limits = [0 1];
            app.propNC_Slider.MajorTicks = [0 0.125 0.25 0.375 0.5 0.625 0.75 0.875 1];
            app.propNC_Slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', ''};
            app.propNC_Slider.ValueChangedFcn = createCallbackFcn(app, @propNC_SliderValueChanged, true);
            app.InputPreset1_Button = uibutton(app.ParametersInitialConiditionsPanel, 'push');
            app.InputPreset1_Button.ButtonPushedFcn = createCallbackFcn(app, @InputPreset1_ButtonPushed, true);
            app.InputPreset1_Button.Text = 'Preset1';
            app.InputPreset2_Button = uibutton(app.ParametersInitialConiditionsPanel, 'push');
            app.InputPreset2_Button.ButtonPushedFcn = createCallbackFcn(app, @InputPreset2_ButtonPushed, true);
            app.InputPreset2_Button.Text = 'Preset2';
            app.InputPreset3_Button = uibutton(app.ParametersInitialConiditionsPanel, 'push');
            app.InputPreset3_Button.ButtonPushedFcn = createCallbackFcn(app, @InputPreset3_ButtonPushed, true);
            app.InputPreset3_Button.Text = 'Preset3';
            app.InputPreset4_Button = uibutton(app.ParametersInitialConiditionsPanel, 'push');
            app.InputPreset4_Button.ButtonPushedFcn = createCallbackFcn(app, @InputPreset4_ButtonPushed, true);
            app.InputPreset4_Button.Text = 'Preset4';
            app.PlotsPanel = uipanel(app.COVID5CUIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.UIAxes1 = uiaxes(app.PlotsPanel);
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            app.UIAxes2 = uiaxes(app.PlotsPanel);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.OutputPreset1_Button = uibutton(app.PlotsPanel, 'push');
            app.OutputPreset1_Button.ButtonPushedFcn = createCallbackFcn(app, @OutputPreset1_ButtonPushed, true);
            app.OutputPreset1_Button.Text = 'Preset1';
            app.OutputPreset2_Button = uibutton(app.PlotsPanel, 'push');
            app.OutputPreset2_Button.Text = 'Preset2';
            app.OutputPreset3_Button = uibutton(app.PlotsPanel, 'push');
            app.OutputPreset3_Button.Text = 'Preset2';
            app.AT_HRCheckBox = uicheckbox(app.PlotsPanel);
            app.AT_HRCheckBox.ValueChangedFcn = createCallbackFcn(app, @AT_HRCheckBoxValueChanged, true);
            app.AT_HRCheckBox.Text = 'AT_HR';
            app.AT_LRCheckBox = uicheckbox(app.PlotsPanel);
            app.AT_LRCheckBox.ValueChangedFcn = createCallbackFcn(app, @AT_LRCheckBoxValueChanged, true);
            app.AT_LRCheckBox.Text = 'AT_LR';
            app.Staff_HRCheckBox = uicheckbox(app.PlotsPanel);
            app.Staff_HRCheckBox.ValueChangedFcn = createCallbackFcn(app, @Staff_HRCheckBoxValueChanged, true);
            app.Staff_HRCheckBox.Text = 'Staff_HR';
            app.Staff_LRCheckBox = uicheckbox(app.PlotsPanel);
            app.Staff_LRCheckBox.ValueChangedFcn = createCallbackFcn(app, @Staff_LRCheckBoxValueChanged, true);
            app.Staff_LRCheckBox.Text = 'Staff_LR';
            app.Students_CCheckBox = uicheckbox(app.PlotsPanel);
            app.Students_CCheckBox.ValueChangedFcn = createCallbackFcn(app, @Students_CCheckBoxValueChanged, true);
            app.Students_CCheckBox.Text = 'Students_C';
            app.Students_NCCheckBox = uicheckbox(app.PlotsPanel);
            app.Students_NCCheckBox.ValueChangedFcn = createCallbackFcn(app, @Students_NCCheckBoxValueChanged, true);
            app.Students_NCCheckBox.Text = 'Students_NC';
            app.DEATHSGaugeLabel = uilabel(app.COVID5CUIFigure);
            app.DEATHSGaugeLabel.Text = 'DEATHS';
            app.Deaths_Gauge = uigauge(app.COVID5CUIFigure, 'circular');
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID5CUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID5CUIFigure)
        end
    end
end